classdef ImageHistogramsAppExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        onewayexpansionSwitch       matlab.ui.control.RockerSwitch
        Label                       matlab.ui.control.Label
        rotateSwitch                matlab.ui.control.Switch
        rotateSwitchLabel           matlab.ui.control.Label
        RESETButton                 matlab.ui.control.Button
        numberofsuperpixelsSpinner  matlab.ui.control.Spinner
        numberofsuperpixelsSpinnerLabel  matlab.ui.control.Label
        shapeSlider                 matlab.ui.control.Slider
        shapeSliderLabel            matlab.ui.control.Label
        SUPERPIXELAGAINButton       matlab.ui.control.Button
        PredefinedImagesLabel       matlab.ui.control.Label
        DropDown                    matlab.ui.control.DropDown
        LoadButton                  matlab.ui.control.Button
        ImageAxes_2                 matlab.ui.control.UIAxes
        RedAxes                     matlab.ui.control.UIAxes
        BlueAxes                    matlab.ui.control.UIAxes
        GreenAxes                   matlab.ui.control.UIAxes
        ImageAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        imagenow; % Description
        image_backup;
    end

    methods (Access = private)

        function updateimage(app,imagefile)

            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end
            end

            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);

                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');

                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes_2,im);
                    app.imagenow=im;
                    app.image_backup=im;
                    superpx(app,app.imagenow);

                    %imagesc(app.ImageAxes_2,im);

                    % Plot the histograms
                    histr = histogram(app.RedAxes, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');

                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
            % Get largest bin count
            maxr = max(histr.BinCounts);
            maxg = max(histg.BinCounts);
            maxb = max(histb.BinCounts);
            maxcount = max([maxr maxg maxb]);

            % Set y axes limits based on largest bin count
            app.RedAxes.YLim = [0 maxcount];
            app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.GreenAxes.YLim = [0 maxcount];
            app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.BlueAxes.YLim = [0 maxcount];
            app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');

        end

        function superpx(app,imgobj)
            if app.rotateSwitch.Value=="Off"
                A=imgobj;
            elseif app.rotateSwitch.Value=="On"
                B=imgobj;
                A=rot90(B);
            else
                uialert(app.UIFigure, 'app.rotateSwitch.Value=="Off"', 'COMPILE Error');
            end
            %A=imgobj;
            [L,N] = superpixels(A,get(app.numberofsuperpixelsSpinner,"Value"),"Compactness",get(app.shapeSlider,"Value"));
            %figure
            BW = boundarymask(L);
            if app.onewayexpansionSwitch.Value=="On"
                L=onewaymg(L,BW);
            end
            %imagesc(app.ImageAxes_2,imoverlay(A,BW,'cyan'))
            outputImage = zeros(size(A),'like',A);
            idx = label2idx(L);
            numRows = size(A,1);
            numCols = size(A,2);
            for labelVal = 1:N
                redIdx = idx{labelVal};
                greenIdx = idx{labelVal}+numRows*numCols;
                blueIdx = idx{labelVal}+2*numRows*numCols;
                outputImage(redIdx) = mean(A(redIdx));
                outputImage(greenIdx) = mean(A(greenIdx));
                outputImage(blueIdx) = mean(A(blueIdx));
            end
            %figure
            imagesc(app.ImageAxes,outputImage)
            app.imagenow=outputImage;
        end
    end

    %methods (Access = private)

    %    function superpixel(app,imgobj)

    %   end
    %end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            app.ImageAxes_2.Visible = 'off';
            app.ImageAxes_2.Colormap = gray(256);
            axis(app.ImageAxes_2, 'image');

            % Update the image and histograms
            updateimage(app, 'peppers.png');
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)

            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                updateimage(app, fname);
            end
        end

        % Button pushed function: SUPERPIXELAGAINButton
        function doagain(app, event)
            %getimage(app.ImageAxes);
            superpx(app,app.imagenow);
        end

        % Value changed function: shapeSlider
        function shapeSliderValueChanged(app, event)
            value = app.shapeSlider.Value;
            superpx(app,app.imagenow);
        end

        % Value changed function: numberofsuperpixelsSpinner
        function numberofsuperpixelsSpinnerValueChanged(app, event)
            value = app.numberofsuperpixelsSpinner.Value;
            superpx(app,app.imagenow);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.imagenow=app.image_backup;
            imagesc(app.ImageAxes,app.imagenow);
        end

        % Value changed function: rotateSwitch
        function rotateSwitchValueChanged(app, event)
            value = app.rotateSwitch.Value;
            imagesc(app.ImageAxes,app.imagenow);
        end

        % Value changed function: onewayexpansionSwitch
        function onewayexpansionSwitchValueChanged(app, event)
            value = app.onewayexpansionSwitch.Value;
            imagesc(app.ImageAxes,app.imagenow);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SUPER PIXEL';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.GreenAxes = uiaxes(app.UIFigure);
            title(app.GreenAxes, 'Green')
            xlabel(app.GreenAxes, 'Intensity')
            ylabel(app.GreenAxes, 'Pixels')
            app.GreenAxes.XLim = [0 255];
            app.GreenAxes.XTick = [0 128 255];
            app.BlueAxes = uiaxes(app.UIFigure);
            title(app.BlueAxes, 'Blue')
            xlabel(app.BlueAxes, 'Intensity')
            ylabel(app.BlueAxes, 'Pixels')
            app.BlueAxes.XLim = [0 255];
            app.BlueAxes.XTick = [0 128 255];
            app.RedAxes = uiaxes(app.UIFigure);
            title(app.RedAxes, 'Red')
            xlabel(app.RedAxes, 'Intensity')
            ylabel(app.RedAxes, 'Pixels')
            app.RedAxes.XLim = [0 255];
            app.RedAxes.XTick = [0 128 255];
            app.ImageAxes_2 = uiaxes(app.UIFigure);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = {'[ ]'};
            app.ImageAxes_2.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Peppers', 'Corn'};
            app.DropDown.ItemsData = {'peppers.png', 'corn.tif'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'peppers.png';
            app.PredefinedImagesLabel = uilabel(app.UIFigure);
            app.PredefinedImagesLabel.Text = 'Predefined Images';
            app.SUPERPIXELAGAINButton = uibutton(app.UIFigure, 'push');
            app.SUPERPIXELAGAINButton.ButtonPushedFcn = createCallbackFcn(app, @doagain, true);
            app.SUPERPIXELAGAINButton.Text = 'SUPER PIXEL AGAIN';
            app.shapeSliderLabel = uilabel(app.UIFigure);
            app.shapeSliderLabel.Text = 'shape';
            app.shapeSlider = uislider(app.UIFigure);
            app.shapeSlider.Limits = [1 20];
            app.shapeSlider.ValueChangedFcn = createCallbackFcn(app, @shapeSliderValueChanged, true);
            app.shapeSlider.Value = 10;
            app.numberofsuperpixelsSpinnerLabel = uilabel(app.UIFigure);
            app.numberofsuperpixelsSpinnerLabel.Text = 'number of superpixels';
            app.numberofsuperpixelsSpinner = uispinner(app.UIFigure);
            app.numberofsuperpixelsSpinner.Step = 50;
            app.numberofsuperpixelsSpinner.Limits = [1 Inf];
            app.numberofsuperpixelsSpinner.ValueChangedFcn = createCallbackFcn(app, @numberofsuperpixelsSpinnerValueChanged, true);
            app.numberofsuperpixelsSpinner.Value = 500;
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.rotateSwitchLabel = uilabel(app.UIFigure);
            app.rotateSwitchLabel.Text = 'rotate';
            app.rotateSwitch = uiswitch(app.UIFigure, 'slider');
            app.rotateSwitch.ValueChangedFcn = createCallbackFcn(app, @rotateSwitchValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'one-way expansion';
            app.onewayexpansionSwitch = uiswitch(app.UIFigure, 'rocker');
            app.onewayexpansionSwitch.ValueChangedFcn = createCallbackFcn(app, @onewayexpansionSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageHistogramsAppExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end